classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        Button                    matlab.ui.control.StateButton
        DivisionsEditFieldLabel   matlab.ui.control.Label
        DivisionsEditField        matlab.ui.control.NumericEditField
        Focus_point_1SliderLabel  matlab.ui.control.Label
        Focus_point_1Slider       matlab.ui.control.Slider
        Focus_point_2SliderLabel  matlab.ui.control.Label
        Focus_point_2Slider       matlab.ui.control.Slider
        min_valueEditFieldLabel   matlab.ui.control.Label
        min_valueEditField        matlab.ui.control.NumericEditField
        max_valueEditFieldLabel   matlab.ui.control.Label
        max_valueEditField        matlab.ui.control.NumericEditField
    end

properties(Access = public)
 
data = [];
x = [];
y =[];
fp1_value = [];
fp2_value = [];
valuemin1 ;
valuemax1 ;
%step = [] ;
%From_range ;
%To_range ;
 %ashifted = [];
end


    methods (Access = private)

        % Callback function: Button, UIAxes
        function UIAxesButtonDown(app, event)
% filename =uigetfile('*./MATLAB Drive');
 [filename,filepath] = uigetfile('*./DESKTOP');
 [ app.x, app.y, app.data] = read_coc_map(fullfile(filepath,filename)); 
            miny1 = min(app.y);
            maxy1 = max(app.y);  
 app.Focus_point_1Slider.Limits = [miny1, maxy1];
 app.Focus_point_2Slider.Limits = [miny1, maxy1];    
 figure(1);
 surf(app.x,app.y,app.data);
 shading interp;
 title('y vs x');
 xlabel ('x range ');
 ylabel ('y d_o  focus');
 zlabel ('Blur Radius (px)');
 [app.fp1_value , app.fp2_value ] = update_plot(app);


% plot(app.UIAxes,x);
 %surf(app.data);
 %surf(range/1e3, min_range:range_step:max_range, coc_map)
 
  
 %app.Focus_point_1Slider.Value = data(:,end); 
 %## app.Focus_point_1Slider.Value = data; 
   % bb = data (end,:);  
   % aa = data(:,end);
 %  app.Focus_point_1Slider.Value = data;
   %app.Focus_point_1Slider.Value= aa;
   %valuex = app.Focus_point_1Slider.Value;
  % yy = valuex*bb;
  % app.UIAxes = app.Focus_point_1Slider.Value;    
  % plot(app.UIAxes,valuex,yy,'b -');


         % app.Focus_point_1Slider.Value = data(:,end);
         %   a  = app.Focus_point_1Slider.Value;
          % x= 0:0.01:pi;
           % y= sin(a*x);
           %plot(app.UIAxes,x,y)
            
  
% plot(app.UIAxes,data);
% legend(app.UIaxes,'slide1','slide2')
 %xlabel ('x range ')
 %ylabel ('y d_o focus')
 %shading interp;


 %xx= figure;
% [n,m] = size(data);
 % n = data(:,1);
  %m = data(1,:);
 
          % data = csvread(filename);
           %  X = data(:,10);
            % Y = data(10, :);
            % Z = sin(X) + cos(Y);
           %[n,m] = size(data);
          % range_step = 1;
          % range = (500:range_step:max_range)*1e3;
          % data = []
         
         % colormap(parula(100));
         %shading interp;
          %  surf(app.UIAxes,X,Y,Z)
            % X = data(:,1);
             %Y = data(:, 1 );
           % data = matrix(N, M);
            %surf(range/1e3, min_range:range_step:max_range,data);
            
            %[X,Y] = meshgrind(1:1:50,1:1:50);
           % X = data(:,5);
           % Y = data(:,3);
          %%  [X,Y] = meshgrid(1:0.5:10,1:20);
         %  Z = sin(X) + cos(Y);
          %  surf(app.UIAxes,X,Y,Z);
         %  [X,Y] = meshgrid(1:0.5:10,1:20);
          %  X = data(:,1,3);
           % Y = data(end,:);
           % plot(app.UIAxes,X,Y);
            % [X,Y] = data(:,:);
             %Z = sin(X) + cos(Y);
              %surf(X,Y,Z)
           
           
            %x = data(:,1);
             %y = data(1,:);
           
          
          % plot(app.UIAxes,X,Y)
          
        %% app.UIAxesButtonDown.value       
         %% plot(app.UIAxesButtonDown.value)
          %  range = coc.xlim([range(1) range(end)]/1e3)
           % focus = coc.ylim([min_range max_range])
       %% app.UIAxesButtonDown.value = generate_coc_map.figure(plot_num);
         %%   [X,Y,Z]= figure(plot_num) 
         
           
            %%%%%%%%
      % [file,path,index]=uigetfile
       %filename= [path file]
       %A = importdata(filename);  
    % coc_map_data.xlsx
      %%filename =['*./MATLAB Drive/coc_map_data.xlsx']
     % [filename,pathname] = uigetfile('*./MATLAB Drive/coc_map_data.xlsx');
      %coc_map_data.xlsx = fullfile(pathname,filename);
      %A = xlsread(coc_map_data.xlsx); % if it is a excel file. else use whatever it is.
      
     %  filename= [ '/MATLAB Drive/coc_map_data.csv']
      %[filename,pathname] = uigetfile('coc_map_data.csv');
      %coc_map_data.csv = fullfile(pathname,filename);
      % xlsread(coc_map_data.csv); % if it is a excel file. else use whatever it is.      
      %full_path = mfilename('fullpath');
       %[startpath,  filename, ext] = fileparts(full_path);
       % plot_num = 1;
           
        %load coc_map_data.csv A
      
      %UIFigure(plot_num)
      %set(gcf,'position',([50,50,800,600]),'color','w')
      %surf(range/1e3, min_range:range_step:max_range, coc_map)
      %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
      
             % data1 = csvread(data);
             %% aa =  app.data(:,167);
              
          %  app.Focus_point_1Slider.Value = aa;
            %%index = app.Focus_point_1Slider.Value;
             %% app.Focus_point_1Slider.Value  =  a(:,index);
            % x= a;
             %y= (a*x);
             %plot(app.UIAxes,x,y) 
             %a  =app.Focus_point_1Slider.Value;
           
            %index = app.Focus_point_1Slider.Value;
         
           % a =  randi(13,[2,50]);
            % a =  randi(13,[2,200]);
            
          %  a = randi(600,[2,5]);
            %plot(app.UIAxes,a);
            
           % a  =app.Focus_point_1Slider.Value;
            %x= 0:0.01:pi;
            %y= sin(a*x);
            %plot(app.UIAxes,x,y)
  
     
        % value = app.Focus_point_1Slider.Value;
           % app.Z = peaks(value);
            %plotsurface(app);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%(working code for slider)
             % a= app.data;
             %index = app.Focus_point_1Slider.Value;
             %value = a(:,index);
             %plot(app.UIAxes,value,'R ');
      
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: DivisionsEditField
        function DivisionsEditFieldValueChanged(app, event)
           
            a = app.DivisionsEditField.Value;
            low =app.valuemin1;
            high = app.valuemax1;
            app.UIAxes.XTick = [low : a : high];
         
           
        %  app.UIAxes.XTick =  ([auto : a : auto]);
          % app.UIAxes.XTick =  ([0 : a : to]);


            %  x= 0:0.01:pi;
           % y= sin(a*x);
            %plot(app.UIAxes,x,y)
  
        end

        % Value changed function: Focus_point_1Slider
        function Focus_point_1SliderValueChanged(app, event)
            
         %  [app.fp1_value , app.fp2_value ] = update_plot(app);
            update_plot(app);


%  
%                hold(app.UIAxes,'off');
%             % index of matrix a skips value of 101  
%              a = app.data1;
%             minx1 = min(app.x);
%             miny1 = min(app.y);
%             maxy1 = max(app.y);
%              
%            % start_value = [minx1 miny1];
%             %start_value = [miny1 minx        
%           index = app.Focus_point_1Slider.Value;
%               index2 = index - minx1 -100 ;
%             value = a (  index2 , : );
%             
%            % plot (app.UIAxes,value,'b  ');
%            plot (app.UIAxes,app.x,value,'b  ');
%             hold(app.UIAxes,'on');
%           %    figure (1);
%            %   surf(app.data1,app.x,app.y);


%%%%%%%%works%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
%             hold(app.UIAxes,'off');
%             % index of matrix a skips value of 101  
%              a = app.data1;
%             minx1 = min(app.x1);
%             miny1 = min(app.y1);
%              
%              % start_value = [minx1 miny1];
%             start_value = [miny1 minx1];
%      
%             rowstart = start_value (1);
%    
%             colstart = start_value (2);
%            
%             app.ashifted = NaN(size (a ,1 ) + rowstart -1 , size (a, 2) + colstart -1);
%             app.ashifted (rowstart:end , colstart:end ) = a ;
%             aa = app.ashifted;
%          
%             index = app.Focus_point_1Slider.Value;
%             value = aa ( (index ), : );
%             
%             plot (app.UIAxes,value,'b  ');
%           % plot (app.UIAxes,app.x,value,'b  ');
%             hold(app.UIAxes,'on');
  %%%%%%%%works%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   


             % c = [ matrix  a(1:end,1:end) ];
            % figure 
             %plot(a);
            % b =  a.';
            % value2 = b ( : , :);
             %figure;
            % plot(aa); 
             %max1 = max(app.x1);
             %min1 = min(app.x1);
            % range = app.x1(1:end);
            % app.UIAxes.XTick = [min1  : max1];
            % app.UIAxes.XLim = [min1 max1];
             
           %  f = uifigure;
           %  s = uislider(f); 
           %  s.Value = 20;
            
           %  value = a(startrow,index);
            % value = a(index,:);
      
            % [index_shifted] = find(aa(1,colstart:end));
       %    app.Focus_point_1Slider.Value =   aa(1,:);
            
            %index2 = (colstart + (index -index) ); 
            %index3 = (index2 + index);                
  %%%%%%    app.Focus_point_1Slider.Limits = [min1, max1];
          
         %    index3 = (index + (index2 - index2));
          % [ j ] = find(rang(1,:));
           
             %%my work%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
             %value = aa ( : ,  (index+100) );
             %%dave email%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
          %  app.Focus_point_1Slider.MajorTicks = range;
           % app.Focus_point_1Slider.ValueChangedFcn = createCallbackFcn(app, @Focus_point_1SliderValueChanged, true);
       
           %  value = a(startrow,index);
            % value = a(index,:);
           
        %     app.Focus_point_1Slider.Value= [index3];
              
              %max= max(1:1)
            
           % S = app.step;
            % From = app.From_range;
             %range_step = S; 
             %To  =  app.To_range;
            % app.Focus_point_1Slider.Limits = [max(xrow) ; min(xrow)];
            
            
            %app.Focus_point_1Slider.Value =  a(1 , :);
              % startrow = a(3 : end, : );
  %            first_row = app.x ;
  %            app.Focus_point_1Slider.Value = first_row ;
           %  app.Focus_point_1Slider.Value = a (  :, 1:end); 
           
            % index = (min1 : max1);


            % index2 = (From:range_step:To);
             %index3 = (index)|(S);
           
            
           %  value = a( startrow ,index);  


            % X = linspace(0,10,20)';
            % Y = (exp(0.25*X));
             %stem(X,Y,'filled')
            
            % app.Focus_point_1Slider.Value =(500:range_step:max_range);
            % app.Focus_point_1Slider.Value =(0:range_step:600);
            % index2 = [1:index:range_step];


              % hold(app.UIAxes,'off');
              %a= app.data;
              %index = app.Focus_point_1Slider.Value;
              %value = a(index, :);
              %value = a(:,index);%if need to change all row spefic colum
              %plot (app.UIAxes,value,'R ');
              %hold(app.UIAxes,'on');


        end

        % Value changed function: Focus_point_2Slider
        function Focus_point_2SliderValueChanged(app, event)
            
            update_plot(app); 
            
            
%           %   hold(app.UIAxes,'off');
%              a = app.data1;
%             minx1 = min(app.x1);
%             miny1 = min(app.y1);
%              
%          % start_value = [minx1 miny1];
%            start_value = [miny1 minx1];
%            rowstart = start_value (1);
%            colstart = start_value (2);
%            
%           app.ashifted = NaN(size (a ,1 ) + rowstart -1 , size (a, 2) + colstart -1);
%           app.ashifted (rowstart:end , colstart:end ) = a ;
%           aa = app.ashifted;
%              index = app.Focus_point_2Slider.Value;
%               value = aa ( (index ), : );
%                plot (app.UIAxes,value,'G  ');
%                hold(app.UIAxes,'on');
%               hold(app.UIAxes,'off');
%             
%               
%     figure (1);
%  surf(app.ashifted);
% 
%  shading interp;
%   title('y vs x');
%  xlabel ('x range ');
%  ylabel ('y d_o  focus');
%  zlabel ('Blur Radius (px)');


%               BB = app.data;
%               index = app.Focus_point_2Slider.Value;
%               %a =  randi(5,[100,300]);
%              % value = a (index,:);
%               value = BB(:,index);
%               %stem(app.UIAxes,value,'G ');
%               %plot(value,app.UIAxes,'G ');
%               plot(app.UIAxes,value,'G ');
%               hold(app.UIAxes,'off');


             %a= app.data;
             %index = app.Focus_point_2Slider.Value;
             %value = a(:,index);
             %plot(app.UIAxes,value,'b ');
             %hold(app.UIAxes,'off');
             
        end

        % Value changed function: min_valueEditField
        function min_valueEditFieldValueChanged(app, event)
             app.valuemin1 = app.min_valueEditField.Value;
             maxlim = app.valuemax1;
             minlim = app.valuemin1; 
             app.UIAxes.XLim = [minlim maxlim];
           %  value = aa ( (index ), : );
            
            
             %value = app.min_valueEditField.Value;
             %max = app.valuemax1;
             %maxlim = ((value * 0) + max);
             %minlim =  value;
             %app.UIAxes.XLim = [minlim maxlim];
            
 
        end

        % Value changed function: max_valueEditField
        function max_valueEditFieldValueChanged(app, event)
            app.valuemax1 = app.max_valueEditField.Value;
            maxlim = app.valuemax1;
            minlim=  app.valuemin1;
           % maxlim =  max;
            app.UIAxes.XLim = [minlim maxlim];
           
           
            % app.valuemax1 = app.max_valueEditField.Value;
            % max = app.valuemax1;
             %maxlim =  max;
             %app.UIAxes.XLim = [minlim maxlim];
        end

        % Callback function
        function stepEditFieldValueChanged(app, event)
            hold(app.UIAxes,'off');
            value = app.stepEditField.Value;
            app.step= value;
            range_step = app.step;
            From = app.From_range;
            To  =  app.To_range;
             a = app.data;
             %index = app.Focus_point_1Slider.Value;
             index2 =(From:range_step:To);
             %index3 = (index)|(S);
            % value = a(:,index);
             valueXX = a(index2,:);  
             plot (app.UIAxes,valueXX,'r ');
             hold(app.UIAxes,'on');
        end

        % Callback function
        function From_rangeEditFieldValueChanged(app, event)
%             value = app.From_rangeEditField.Value;
%             app.From_range = value;
%             stem (app.UIAxes,value,'k ');
        end

        % Callback function
        function To_rangeEditFieldValueChanged(app, event)
            value = app.To_rangeEditField.Value;
            app.To_range =value;
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X range??')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Button.Text = 'Button';
            app.DivisionsEditFieldLabel = uilabel(app.UIFigure);
            app.DivisionsEditFieldLabel.Text = 'Divisions';
            app.DivisionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @DivisionsEditFieldValueChanged, true);
            app.Focus_point_1SliderLabel = uilabel(app.UIFigure);
            app.Focus_point_1SliderLabel.Text = 'Focus_point_1';
            app.Focus_point_1Slider = uislider(app.UIFigure);
            app.Focus_point_1Slider.Limits = [500 1000];
            app.Focus_point_1Slider.ValueChangedFcn = createCallbackFcn(app, @Focus_point_1SliderValueChanged, true);
            app.Focus_point_1Slider.Value = 600;
            app.Focus_point_2SliderLabel = uilabel(app.UIFigure);
            app.Focus_point_2SliderLabel.Text = 'Focus_point_2';
            app.Focus_point_2Slider = uislider(app.UIFigure);
            app.Focus_point_2Slider.Limits = [600 1000];
            app.Focus_point_2Slider.ValueChangedFcn = createCallbackFcn(app, @Focus_point_2SliderValueChanged, true);
            app.Focus_point_2Slider.Value = 600;
            app.min_valueEditFieldLabel = uilabel(app.UIFigure);
            app.min_valueEditFieldLabel.Text = 'min_value';
            app.min_valueEditField = uieditfield(app.UIFigure, 'numeric');
            app.min_valueEditField.ValueChangedFcn = createCallbackFcn(app, @min_valueEditFieldValueChanged, true);
            app.max_valueEditFieldLabel = uilabel(app.UIFigure);
            app.max_valueEditFieldLabel.Text = 'max_value';
            app.max_valueEditField = uieditfield(app.UIFigure, 'numeric');
            app.max_valueEditField.ValueChangedFcn = createCallbackFcn(app, @max_valueEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end